classdef StudHRV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end


    properties (Access = public)
        TabGroup             matlab.ui.container.TabGroup
        SingleAnalysisTab    matlab.ui.container.Tab
        BatchAnalysisTab     matlab.ui.container.Tab
        ArrhythmiaTab        matlab.ui.container.Tab
        
        % Панель загрузки данных (одиночный анализ)
        LoadPanel            matlab.ui.container.Panel
        LoadFileButton       matlab.ui.control.Button
        FilePathEdit         matlab.ui.control.EditField
        LoadedFilesList      matlab.ui.control.ListBox
        
        % Панель параметров
        ParametersPanel      matlab.ui.container.Panel
        MinPeakHeightLabel   matlab.ui.control.Label
        MinPeakHeightEdit    matlab.ui.control.NumericEditField
        BinWidthLabel        matlab.ui.control.Label
        BinWidthEdit         matlab.ui.control.NumericEditField
        CalculateButton      matlab.ui.control.Button
        
        % Панель графиков
        GraphsPanel          matlab.ui.container.Panel
        RhythmogramAxes      matlab.ui.control.UIAxes
        TINNHistogramAxes    matlab.ui.control.UIAxes
        SpectrumAxes         matlab.ui.control.UIAxes
        
        % Панель результатов
        ResultsPanel         matlab.ui.container.Panel
        ResultsTable         matlab.ui.control.Table
        
        % Элементы пакетного анализа
        BatchLoadBeforePanel matlab.ui.container.Panel
        LoadBeforeButton     matlab.ui.control.Button
        BeforeFilesList      matlab.ui.control.ListBox
        
        BatchLoadAfterPanel  matlab.ui.container.Panel
        LoadAfterButton      matlab.ui.control.Button
        AfterFilesList       matlab.ui.control.ListBox
        
        BatchParametersPanel matlab.ui.container.Panel
        BatchCalculateButton matlab.ui.control.Button
        
        % Для сравнительных гистограмм TINN
        ComparativeHistPanel      matlab.ui.container.Panel
        ComparativeHistAxes       matlab.ui.control.UIAxes
        NormalizedHistButton      matlab.ui.control.Button
        
        % Панели результатов пакетного анализа
        BatchResultsPanel    matlab.ui.container.Panel
        BatchResultsTable    matlab.ui.control.Table
        StatisticsPanel      matlab.ui.container.Panel
        StatisticsTable      matlab.ui.control.Table
        
        % Свойства для пакетного анализа
        BatchMinPeakHeightLabel   matlab.ui.control.Label
        BatchMinPeakHeightEdit    matlab.ui.control.NumericEditField
        BatchBinWidthLabel        matlab.ui.control.Label
        BatchBinWidthEdit         matlab.ui.control.NumericEditField
        
        % Свойства для кнопок масштабирования
        RhythmogramZoomPanel      matlab.ui.container.Panel
        RhythmogramZoomInButton   matlab.ui.control.Button
        RhythmogramResetButton    matlab.ui.control.Button
        
        SpectrumZoomPanel         matlab.ui.container.Panel
        SpectrumZoomInButton      matlab.ui.control.Button
        SpectrumResetButton       matlab.ui.control.Button
        
        % Свойства для вкладки анализа аритмии
        DetailedArrhythmiaButton  matlab.ui.control.Button
        ArrhythmiaInterpretationText matlab.ui.control.TextArea
        ComparativeHistogramAxes  matlab.ui.control.UIAxes
        ArrhythmiaStatsTable      matlab.ui.control.Table
        
        % Данные приложения
        CurrentData          struct
        BeforeData           struct
        AfterData            struct
        HRVResults           struct
        BatchResults         struct
    end

    methods (Access = public)
    
        function initializeComponents(app)
            % Настройка вкладок
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Position = [10 10 1180 680];
            
            % Вкладка одиночного анализа
            app.SingleAnalysisTab = uitab(app.TabGroup, 'Title', 'Одиночный анализ');
            
            % Вкладка пакетного анализа
            app.BatchAnalysisTab = uitab(app.TabGroup, 'Title', 'Пакетный анализ');
            
            % Вкладка анализа аритмии
            app.ArrhythmiaTab = uitab(app.TabGroup, 'Title', 'Анализ аритмии');
            
            % Создание компонентов для одиночного анализа
            createSingleAnalysisComponents(app);
            
            % Создание компонентов для пакетного анализа
            createBatchAnalysisComponents(app);
            
            % Создание компонентов для анализа аритмии
            createArrhythmiaAnalysisTab(app);
        end
        
        function createSingleAnalysisComponents(app)
            % Панель загрузки данных
            app.LoadPanel = uipanel(app.SingleAnalysisTab);
            app.LoadPanel.Title = 'Загрузка данных';
            app.LoadPanel.Position = [20 550 400 100];
            
            app.LoadFileButton = uibutton(app.LoadPanel, 'push');
            app.LoadFileButton.Position = [10 10 100 30];
            app.LoadFileButton.Text = 'Загрузить файл';
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadFileButtonPushed, true);
            
            app.FilePathEdit = uieditfield(app.LoadPanel, 'text');
            app.FilePathEdit.Position = [120 10 270 30];
            app.FilePathEdit.Editable = 'off';
            
            app.LoadedFilesList = uilistbox(app.LoadPanel);
            app.LoadedFilesList.Position = [10 50 380 40];
            app.LoadedFilesList.Items = {};
            
            % Панель параметров
            app.ParametersPanel = uipanel(app.SingleAnalysisTab);
            app.ParametersPanel.Title = 'Параметры анализа';
            app.ParametersPanel.Position = [20 400 400 140];
            
            app.MinPeakHeightLabel = uilabel(app.ParametersPanel);
            app.MinPeakHeightLabel.Position = [10 80 150 22];
            app.MinPeakHeightLabel.Text = 'Минимальная высота пика:';
            
            app.MinPeakHeightEdit = uieditfield(app.ParametersPanel, 'numeric');
            app.MinPeakHeightEdit.Position = [170 80 100 22];
            
            app.BinWidthLabel = uilabel(app.ParametersPanel);
            app.BinWidthLabel.Position = [10 40 150 22];
            app.BinWidthLabel.Text = 'Ширина бина гистограммы:';
            
            app.BinWidthEdit = uieditfield(app.ParametersPanel, 'numeric');
            app.BinWidthEdit.Position = [170 40 100 22];
            
            app.CalculateButton = uibutton(app.ParametersPanel, 'push');
            app.CalculateButton.Position = [280 20 100 30];
            app.CalculateButton.Text = 'Рассчитать';
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            
            % Панель графиков
            app.GraphsPanel = uipanel(app.SingleAnalysisTab);
            app.GraphsPanel.Title = 'Графики';
            app.GraphsPanel.Position = [440 20 720 630];
            
            % Ритмограмма с кнопками масштабирования
            app.RhythmogramAxes = uiaxes(app.GraphsPanel);
            app.RhythmogramAxes.Position = [20 400 680 200];
            title(app.RhythmogramAxes, 'Ритмограмма с R-пиками');
            
            % Панель кнопок для ритмограммы
            app.RhythmogramZoomPanel = uipanel(app.GraphsPanel);
            app.RhythmogramZoomPanel.BorderType = 'none';
            app.RhythmogramZoomPanel.BackgroundColor = [0.94 0.94 0.94];
            app.RhythmogramZoomPanel.Position = [600 570 100 30];
            
            app.RhythmogramZoomInButton = uibutton(app.RhythmogramZoomPanel, 'push');
            app.RhythmogramZoomInButton.Position = [5 5 40 20];
            app.RhythmogramZoomInButton.Text = '+';
            app.RhythmogramZoomInButton.FontSize = 12;
            app.RhythmogramZoomInButton.FontWeight = 'bold';
            app.RhythmogramZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @rhythmogramZoomInButtonPushed, true);
            
            app.RhythmogramResetButton = uibutton(app.RhythmogramZoomPanel, 'push');
            app.RhythmogramResetButton.Position = [55 5 40 20];
            app.RhythmogramResetButton.Text = '⟳';
            app.RhythmogramResetButton.FontSize = 12;
            app.RhythmogramResetButton.ButtonPushedFcn = createCallbackFcn(app, @rhythmogramResetButtonPushed, true);
            
            % Гистограмма TINN
            app.TINNHistogramAxes = uiaxes(app.GraphsPanel);
            app.TINNHistogramAxes.Position = [20 200 680 180];
            title(app.TINNHistogramAxes, 'Гистограмма TINN');
            
            % Осциллограмма (спектральный анализ) с кнопками масштабирования
            app.SpectrumAxes = uiaxes(app.GraphsPanel);
            app.SpectrumAxes.Position = [20 20 680 160];
            title(app.SpectrumAxes, 'Спектральный анализ');
            
            % Панель кнопок для осциллограммы
            app.SpectrumZoomPanel = uipanel(app.GraphsPanel);
            app.SpectrumZoomPanel.BorderType = 'none';
            app.SpectrumZoomPanel.BackgroundColor = [0.94 0.94 0.94];
            app.SpectrumZoomPanel.Position = [600 170 100 30];
            
            app.SpectrumZoomInButton = uibutton(app.SpectrumZoomPanel, 'push');
            app.SpectrumZoomInButton.Position = [5 5 40 20];
            app.SpectrumZoomInButton.Text = '+';
            app.SpectrumZoomInButton.FontSize = 12;
            app.SpectrumZoomInButton.FontWeight = 'bold';
            app.SpectrumZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @spectrumZoomInButtonPushed, true);
            
            app.SpectrumResetButton = uibutton(app.SpectrumZoomPanel, 'push');
            app.SpectrumResetButton.Position = [55 5 40 20];
            app.SpectrumResetButton.Text = '⟳';
            app.SpectrumResetButton.FontSize = 12;
            app.SpectrumResetButton.ButtonPushedFcn = createCallbackFcn(app, @spectrumResetButtonPushed, true);
            
            % Панель результатов
            app.ResultsPanel = uipanel(app.SingleAnalysisTab);
            app.ResultsPanel.Title = 'Результаты';
            app.ResultsPanel.Position = [20 20 400 370];
            
            app.ResultsTable = uitable(app.ResultsPanel);
            app.ResultsTable.Position = [10 10 380 350];
            app.ResultsTable.ColumnName = {'Параметр', 'Значение'};
            app.ResultsTable.ColumnWidth = {200, 150};
        end
        
        function createBatchAnalysisComponents(app)
            % Панель загрузки данных до нагрузки
            app.BatchLoadBeforePanel = uipanel(app.BatchAnalysisTab);
            app.BatchLoadBeforePanel.Title = 'Данные до нагрузки';
            app.BatchLoadBeforePanel.Position = [20 580 350 80];
            
            app.LoadBeforeButton = uibutton(app.BatchLoadBeforePanel, 'push');
            app.LoadBeforeButton.Position = [10 10 100 30];
            app.LoadBeforeButton.Text = 'Загрузить';
            app.LoadBeforeButton.ButtonPushedFcn = createCallbackFcn(app, @loadBeforeButtonPushed, true);
            
            app.BeforeFilesList = uilistbox(app.BatchLoadBeforePanel);
            app.BeforeFilesList.Position = [120 10 220 50];
            app.BeforeFilesList.Items = {};
            
            % Панель загрузки данных после нагрузки
            app.BatchLoadAfterPanel = uipanel(app.BatchAnalysisTab);
            app.BatchLoadAfterPanel.Title = 'Данные после нагрузки';
            app.BatchLoadAfterPanel.Position = [400 580 350 80];
            
            app.LoadAfterButton = uibutton(app.BatchLoadAfterPanel, 'push');
            app.LoadAfterButton.Position = [10 10 100 30];
            app.LoadAfterButton.Text = 'Загрузить';
            app.LoadAfterButton.ButtonPushedFcn = createCallbackFcn(app, @loadAfterButtonPushed, true);
            
            app.AfterFilesList = uilistbox(app.BatchLoadAfterPanel);
            app.AfterFilesList.Position = [120 10 220 50];
            app.AfterFilesList.Items = {};
            
            % Панель параметров пакетного анализа
            app.BatchParametersPanel = uipanel(app.BatchAnalysisTab);
            app.BatchParametersPanel.Title = 'Параметры анализа';
            app.BatchParametersPanel.Position = [20 500 730 70];
            
            % Параметры для пакетного анализа
            app.BatchMinPeakHeightLabel = uilabel(app.BatchParametersPanel);
            app.BatchMinPeakHeightLabel.Position = [10 30 150 22];
            app.BatchMinPeakHeightLabel.Text = 'Минимальная высота пика:';
            
            app.BatchMinPeakHeightEdit = uieditfield(app.BatchParametersPanel, 'numeric');
            app.BatchMinPeakHeightEdit.Position = [170 30 100 22];
            app.BatchMinPeakHeightEdit.Value = 0.5;
            
            app.BatchBinWidthLabel = uilabel(app.BatchParametersPanel);
            app.BatchBinWidthLabel.Position = [280 30 150 22];
            app.BatchBinWidthLabel.Text = 'Ширина бина гистограммы:';
            
            app.BatchBinWidthEdit = uieditfield(app.BatchParametersPanel, 'numeric');
            app.BatchBinWidthEdit.Position = [440 30 100 22];
            app.BatchBinWidthEdit.Value = 10;
            
            app.BatchCalculateButton = uibutton(app.BatchParametersPanel, 'push');
            app.BatchCalculateButton.Position = [550 20 120 30];
            app.BatchCalculateButton.Text = 'Выполнить анализ';
            app.BatchCalculateButton.ButtonPushedFcn = createCallbackFcn(app, @batchCalculateButtonPushed, true);
            
            % Панель для сравнительных гистограмм
            app.ComparativeHistPanel = uipanel(app.BatchAnalysisTab);
            app.ComparativeHistPanel.Title = 'Сравнительные гистограммы TINN';
            app.ComparativeHistPanel.Position = [20 50 730 260];
            
            app.ComparativeHistAxes = uiaxes(app.ComparativeHistPanel);
            app.ComparativeHistAxes.Position = [10 40 710 210];
            title(app.ComparativeHistAxes, 'Сравнительные гистограммы распределения TINN');
            xlabel(app.ComparativeHistAxes, 'TINN (мс)');
            ylabel(app.ComparativeHistAxes, 'Количество наблюдений');
            grid(app.ComparativeHistAxes, 'on');
            
            % Кнопка для нормализованных гистограмм
            app.NormalizedHistButton = uibutton(app.ComparativeHistPanel, 'push');
            app.NormalizedHistButton.Position = [600 10 120 25];
            app.NormalizedHistButton.Text = 'Норм. гистограммы';
            app.NormalizedHistButton.ButtonPushedFcn = createCallbackFcn(app, @normalizedHistButtonPushed, true);
            
            % Панель результатов пакетного анализа (таблица статистики TINN)
            app.BatchResultsPanel = uipanel(app.BatchAnalysisTab);
            app.BatchResultsPanel.Title = 'Результаты статистики TINN';
            app.BatchResultsPanel.Position = [20 320 350 150];
            
            app.BatchResultsTable = uitable(app.BatchResultsPanel);
            app.BatchResultsTable.Position = [10 10 330 120];
            app.BatchResultsTable.ColumnName = {'Параметр', 'До', 'После'};
            app.BatchResultsTable.ColumnWidth = {150, 90, 90};
            app.BatchResultsTable.RowName = {};
            
            % Панель статистических тестов
            app.StatisticsPanel = uipanel(app.BatchAnalysisTab);
            app.StatisticsPanel.Title = 'Результаты статистических тестов';
            app.StatisticsPanel.Position = [400 320 350 150];
            
            app.StatisticsTable = uitable(app.StatisticsPanel);
            app.StatisticsTable.Position = [10 10 330 120];
            app.StatisticsTable.ColumnName = {'Статистика', 'Значение', 'Интерпретация'};
            app.StatisticsTable.ColumnWidth = {150, 90, 90};
            app.StatisticsTable.RowName = {};
        end
        
        function configureAxes(app)
            % Настройка внешнего вида осей
            grid(app.RhythmogramAxes, 'on');
            grid(app.TINNHistogramAxes, 'on');
            grid(app.SpectrumAxes, 'on');
        end
    end

    methods (Access = public)
    
        function loadFileButtonPushed(app, event)
            % Загрузка одиночного файла
            try
                [filename, pathname] = uigetfile('*.mat', 'Выберите файл с данными');
                if isequal(filename, 0)
                    return;
                end
                
                fullpath = fullfile(pathname, filename);
                app.FilePathEdit.Value = fullpath;
                app.LoadedFilesList.Items = {filename};
                
                % Загрузка файла .mat
                loadedData = load(fullpath);
                
                % Валидация данных с новой версией функции
                [validatedData, isValid, errorMsg] = validateData(loadedData);
                
                if ~isValid
                    uialert(app.UIFigure, errorMsg, 'Ошибка данных');
                    return;
                end
                
                app.CurrentData = validatedData;
                uialert(app.UIFigure, 'Файл успешно загружен', 'Успех');
                
            catch ME
                uialert(app.UIFigure, sprintf('Ошибка при загрузке файла: %s', ME.message), 'Ошибка');
            end
        end
        
        function plotTINNBoxPlot(app, beforeTINN, afterTINN)
            % Построение box plot для сравнения распределений TINN
            
            fig = figure('Name', 'Box Plot распределений TINN', ...
                'Position', [100 100 600 400], 'NumberTitle', 'off');
            
            % Подготовка данных для boxplot
            data = [beforeTINN(:); afterTINN(:)];
            group = [ones(length(beforeTINN), 1); 2*ones(length(afterTINN), 1)];
            
            % Box plot
            boxplot(data, group, 'Labels', {'До нагрузки', 'После нагрузки'});
            
            % Добавляем точки данных
            hold on;
            scatter(group + 0.1 * randn(size(group)), data, 20, 'filled', ...
                'MarkerFaceAlpha', 0.3, 'MarkerEdgeColor', 'none');
            hold off;
            
            title('Box Plot распределений TINN');
            ylabel('TINN (мс)');
            grid on;
            
            % Добавляем статистическую информацию
            annotation('textbox', [0.7 0.15 0.25 0.2], 'String', ...
                sprintf('До: N=%d\nПосле: N=%d\np-value: %.4f', ...
                length(beforeTINN), length(afterTINN), ...
                ranksum(beforeTINN, afterTINN)), ...
                'FitBoxToText', 'on', 'BackgroundColor', 'white');
        end
        
        function normalizedHistButtonPushed(app, ~)
            % Отображение нормализованных гистограмм
    
            if isempty(app.BatchResults) || ...
               ~isfield(app.BatchResults, 'beforeTINN') || ...
               ~isfield(app.BatchResults, 'afterTINN')
                uialert(app.UIFigure, 'Сначала выполните пакетный анализ', 'Ошибка');
                return;
            end
            
            binWidth = app.BatchBinWidthEdit.Value;
            plotNormalizedComparativeHistograms(app, app.BatchResults.beforeTINN, ...
                app.BatchResults.afterTINN, binWidth);
        end
        
        function loadBeforeButtonPushed(app, event)
            % Загрузка файлов до нагрузки
            try
                [filenames, pathname] = uigetfile('*.mat', 'Выберите файлы до нагрузки', 'MultiSelect', 'on');
                
                if isequal(filenames, 0)
                    return;
                end
                
                if ~iscell(filenames)
                    filenames = {filenames};
                end
                
                app.BeforeFilesList.Items = filenames;
                
                % Загрузка всех файлов
                app.BeforeData = struct();
                
                for i = 1:length(filenames)
                    fullpath = fullfile(pathname, filenames{i});
                    
                    % Загрузка файла .mat
                    loadedData = load(fullpath);
                    
                    % Валидация данных с новой версией функции
                    [validatedData, isValid, errorMsg] = validateData(loadedData);
                    
                    if ~isValid
                        uialert(app.UIFigure, sprintf('Ошибка в файле %s: %s', filenames{i}, errorMsg), 'Ошибка данных');
                        return;
                    end
                    
                    % Сохранение данных в структуру
                    app.BeforeData(i).filename = filenames{i};
                    app.BeforeData(i).data = validatedData;
                end
            
                uialert(app.UIFigure, sprintf('Загружено %d файлов до нагрузки', length(filenames)), 'Успех');
            
            catch ME
                uialert(app.UIFigure, sprintf('Ошибка при загрузке файлов: %s', ME.message), 'Ошибка');
            end
        end
        
        function loadAfterButtonPushed(app, event)
            % Загрузка файлов после нагрузки
            try
                [filenames, pathname] = uigetfile('*.mat', 'Выберите файлы после нагрузки', 'MultiSelect', 'on');
                
                if isequal(filenames, 0)
                    return;
                end
                
                if ~iscell(filenames)
                    filenames = {filenames};
                end
                
                app.AfterFilesList.Items = filenames;
            
                % Загрузка всех файлов
                app.AfterData = struct();
                
                for i = 1:length(filenames)
                    fullpath = fullfile(pathname, filenames{i});
                    
                    % Загрузка файла .mat
                    loadedData = load(fullpath);
                    
                    % Валидация данных (обновлённая версия)
                    [validatedData, isValid, errorMsg] = validateData(loadedData);
                    
                    if ~isValid
                        uialert(app.UIFigure, sprintf('Ошибка в файле %s: %s', filenames{i}, errorMsg), 'Ошибка данных');
                        return;
                    end
                    
                    % Сохранение данных в структуру
                    app.AfterData(i).filename = filenames{i};
                    app.AfterData(i).data = validatedData;
                end
            
                uialert(app.UIFigure, sprintf('Загружено %d файлов после нагрузки', length(filenames)), 'Успех');
            
            catch ME
                uialert(app.UIFigure, sprintf('Ошибка при загрузке файлов: %s', ME.message), 'Ошибка');
            end
        end
        
        function calculateButtonPushed(app, event)
            % Расчёт параметров ВСР для одиночного файла
            try
                if isempty(app.CurrentData)
                    uialert(app.UIFigure, 'Сначала загрузите файл с данными', 'Ошибка');
                    return;
                end
                
                % Получение параметров из интерфейса
                minPeakHeight = app.MinPeakHeightEdit.Value;
                binWidth = app.BinWidthEdit.Value;
                
                % Диагностика данных (теперь как метод класса)
                diagnosticInfo = analyzeDataDiagnostics(app, app.CurrentData, minPeakHeight);
                
                % Проверка данных
                if diagnosticInfo.nPeaks < 2
                    uialert(app.UIFigure, 'Обнаружено менее 2 R-пиков. Уменьшите минимальную высоту пика.', 'Ошибка');
                    return;
                end
                
                % Расчёт параметров ВСР
                app.HRVResults = calculateHRV(app.CurrentData, minPeakHeight, binWidth);
                
                % Отображение результатов в таблице
                updateResultsTable(app);
                
                % Построение графиков
                plotRhythmogram(app.RhythmogramAxes, app.CurrentData, app.HRVResults, minPeakHeight);
                plotTINNHistogram(app.TINNHistogramAxes, app.HRVResults, binWidth);
                plotSpectrum(app.SpectrumAxes, app.CurrentData);
                
                uialert(app.UIFigure, 'Расчёт завершён успешно', 'Успех');
                
            catch ME
                uialert(app.UIFigure, sprintf('Ошибка при расчёте: %s', ME.message), 'Ошибка расчёта');
            end
        end
        
        function diagnosticInfo = analyzeDataDiagnostics(app, data, minPeakHeight)
            % Диагностическая информация о данных
            
            diagnosticInfo = struct();
            
            amplitude = data.amplitude;
            time = data.time;
            
            % Базовая статистика
            diagnosticInfo.amplitudeRange = [min(amplitude), max(amplitude)];
            diagnosticInfo.timeRange = [min(time), max(time)];
            diagnosticInfo.samplingRate = 1/mean(diff(time));
            
            % Детекция пиков
            [peaks, locations] = findpeaks(amplitude, 'MinPeakHeight', minPeakHeight);
            
            diagnosticInfo.nPeaks = length(peaks);
            diagnosticInfo.peakHeights = peaks;
            diagnosticInfo.peakLocations = locations;
            
            if length(locations) >= 2
                RRintervals = diff(time(locations));
                diagnosticInfo.RRintervals = RRintervals;
                diagnosticInfo.meanRR = mean(RRintervals);
                diagnosticInfo.stdRR = std(RRintervals);
                
                % Определение единиц
                if diagnosticInfo.meanRR < 5
                    diagnosticInfo.timeUnits = 'seconds';
                    diagnosticInfo.convertedMeanRR = diagnosticInfo.meanRR * 1000;
                else
                    diagnosticInfo.timeUnits = 'milliseconds';
                    diagnosticInfo.convertedMeanRR = diagnosticInfo.meanRR;
                end
            end
            
            % Вывод в консоль
            fprintf('=== Диагностика данных ===\n');
            fprintf('Амплитуда: [%.2f, %.2f]\n', diagnosticInfo.amplitudeRange(1), diagnosticInfo.amplitudeRange(2));
            fprintf('Время: [%.2f, %.2f]\n', diagnosticInfo.timeRange(1), diagnosticInfo.timeRange(2));
            fprintf('Частота дискретизации: %.2f Гц\n', diagnosticInfo.samplingRate);
            fprintf('Найдено пиков: %d\n', diagnosticInfo.nPeaks);
            if length(locations) >= 2
                fprintf('Средний RR: %.2f (%s)\n', diagnosticInfo.meanRR, diagnosticInfo.timeUnits);
                fprintf('Средний RR (конвертированный): %.2f мс\n', diagnosticInfo.convertedMeanRR);
            end
            fprintf('======================\n');
        end
        
        function debugBatchData(app)
            % Отладочная функция для проверки данных
            fprintf('\n=== ОТЛАДКА ДАННЫХ ПАКЕТНОГО АНАЛИЗА ===\n');
            
            if ~isempty(app.BeforeData)
                fprintf('Группа "до нагрузки": %d файлов\n', length(app.BeforeData));
                for i = 1:min(3, length(app.BeforeData))
                    fprintf('  Файл %d: %s\n', i, app.BeforeData(i).filename);
                    if isfield(app.BeforeData(i), 'data')
                        fprintf('    Амплитуда: %d точек, Время: %d точек\n', ...
                            length(app.BeforeData(i).data.amplitude), ...
                            length(app.BeforeData(i).data.time));
                    end
                end
            else
                fprintf('Группа "до нагрузки": ПУСТО\n');
            end
            
            if ~isempty(app.AfterData)
                fprintf('Группа "после нагрузки": %d файлов\n', length(app.AfterData));
                for i = 1:min(3, length(app.AfterData))
                    fprintf('  Файл %d: %s\n', i, app.AfterData(i).filename);
                    if isfield(app.AfterData(i), 'data')
                        fprintf('    Амплитуда: %d точек, Время: %d точек\n', ...
                            length(app.AfterData(i).data.amplitude), ...
                            length(app.AfterData(i).data.time));
                    end
                end
            else
                fprintf('Группа "после нагрузки": ПУСТО\n');
            end
            
            fprintf('Параметры анализа:\n');
            fprintf('  minPeakHeight: %.2f\n', app.BatchMinPeakHeightEdit.Value);
            fprintf('  binWidth: %.2f\n', app.BatchBinWidthEdit.Value);
            fprintf('=====================================\n');
        end
        
        function plotNormalizedComparativeHistograms(app, beforeTINN, afterTINN, binWidth)
            % Построение нормализованных сравнительных гистограмм
    
            % Создаем новое окно для нормализованных гистограмм
            fig = figure('Name', 'Нормализованные сравнительные гистограммы TINN', ...
                'Position', [100 100 800 600], 'NumberTitle', 'off');
            
            ax = axes('Parent', fig);
            
            % Определяем общий диапазон
            minTINN = min([beforeTINN, afterTINN]);
            maxTINN = max([beforeTINN, afterTINN]);
            
            % Создаем общие границы бинов
            edges = floor(minTINN/binWidth)*binWidth : binWidth : ceil(maxTINN/binWidth)*binWidth;
            
            hold(ax, 'on');
            
            % Нормализованные гистограммы (плотность вероятности)
            histogram(ax, beforeTINN, edges, 'Normalization', 'pdf', ...
                'FaceColor', 'blue', 'FaceAlpha', 0.5, 'EdgeColor', 'blue', ...
                'DisplayName', 'До нагрузки (PDF)');
            
            histogram(ax, afterTINN, edges, 'Normalization', 'pdf', ...
                'FaceColor', 'red', 'FaceAlpha', 0.5, 'EdgeColor', 'red', ...
                'DisplayName', 'После нагрузки (PDF)');
            
            % Ядерные оценки плотности
            if length(beforeTINN) > 1
                [f_before, xi_before] = ksdensity(beforeTINN);
                plot(ax, xi_before, f_before, 'b-', 'LineWidth', 2, ...
                    'DisplayName', 'KDE до нагрузки');
            end
            
            if length(afterTINN) > 1
                [f_after, xi_after] = ksdensity(afterTINN);
                plot(ax, xi_after, f_after, 'r-', 'LineWidth', 2, ...
                    'DisplayName', 'KDE после нагрузки');
            end
            
            hold(ax, 'off');
            
            % Настройка графика
            legend(ax, 'Location', 'best');
            title(ax, 'Нормализованные распределения TINN');
            xlabel(ax, 'TINN (мс)');
            ylabel(ax, 'Плотность вероятности');
            grid(ax, 'on');
            
            % Добавляем кнопку сохранения
            uicontrol(fig, 'Style', 'pushbutton', 'String', 'Сохранить график', ...
                'Position', [650 10 120 30], ...
                'Callback', @(src, evt) saveFigureAsImage(fig));
        end
        
        function saveFigureAsImage(fig)
            % Сохранение графика в файл
            [filename, pathname] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.eps'}, ...
                'Сохранить график как');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            
            fullpath = fullfile(pathname, filename);
            exportgraphics(fig, fullpath, 'Resolution', 300);
            fprintf('График сохранен в файл: %s\n', fullpath);
        end
        
        function batchCalculateButtonPushed(app, event)
            % Пакетный анализ - упрощённая версия
            debugBatchData(app);
            try
                % Проверка наличия данных
                if isempty(app.BeforeData)
                    uialert(app.UIFigure, 'Загрузите данные до нагрузки', 'Ошибка');
                    return;
                end
                if isempty(app.AfterData)
                    uialert(app.UIFigure, 'Загрузите данные после нагрузки', 'Ошибка');
                    return;
                end
                
                % Получение параметров
                minPeakHeight = app.BatchMinPeakHeightEdit.Value;
                binWidth = app.BatchBinWidthEdit.Value;
                
                % Валидация параметров
                if isnan(minPeakHeight) || minPeakHeight <= 0
                    uialert(app.UIFigure, 'Некорректная минимальная высота пика', 'Ошибка');
                    return;
                end
                if isnan(binWidth) || binWidth <= 0
                    uialert(app.UIFigure, 'Некорректная ширина бина', 'Ошибка');
                    return;
                end
                
                % Информационное сообщение
                uialert(app.UIFigure, 'Начинаю пакетный анализ...', 'Информация', 'Icon', 'info');
                
                % Выполнение анализа
                app.BatchResults = batchAnalysis(app.BeforeData, app.AfterData, minPeakHeight, binWidth);
                
                % Отображение результатов в таблицах
                updateBatchResultsTable(app);
                
                % Построение сравнительных гистограмм
                if isfield(app.BatchResults, 'beforeTINN') && isfield(app.BatchResults, 'afterTINN')
                    plotComparativeTINNHistograms(app, app.BatchResults.beforeTINN, ...
                        app.BatchResults.afterTINN, binWidth);
                end
                
                uialert(app.UIFigure, 'Пакетный анализ успешно завершён!', 'Успех');
                
            catch ME
                % Детализация ошибки
                errorMsg = sprintf('Ошибка при пакетном анализе:\n\n%s\n\n', ME.message);
                errorMsg = [errorMsg 'Проверьте:\n'];
                errorMsg = [errorMsg '1. Корректность загруженных данных\n'];
                errorMsg = [errorMsg '2. Параметры minPeakHeight и binWidth\n'];
                errorMsg = [errorMsg '3. Наличие достаточного количества R-пиков в данных'];
                
                uialert(app.UIFigure, errorMsg, 'Ошибка анализа');
            end
        end
        
        function updateResultsTable(app)
            % Обновление таблицы результатов с добавлением анализа аритмии
            if isempty(app.HRVResults)
                return;
            end
            
            results = app.HRVResults;
            
            % Основные параметры ВСР
            basicData = {
                'Среднее RR (мс)', sprintf('%.2f', results.meanRR);
                'SDNN (мс)', sprintf('%.2f', results.SDNN);
                'RMSSD (мс)', sprintf('%.2f', results.RMSSD);
                'NN50', sprintf('%d', results.NN50);
                'pNN50 (%)', sprintf('%.2f', results.pNN50);
                'TINN (мс)', sprintf('%.2f', results.TINN);
                'Коэффициент вариации (%)', sprintf('%.2f', results.CV);
                'LF power', sprintf('%.4f', results.LF);
                'HF power', sprintf('%.4f', results.HF);
                'LF/HF ratio', sprintf('%.2f', results.LFHFratio);
            };
            
            % Анализ аритмии
            if isfield(results, 'arrhythmiaAnalysis')
                arrhythmiaData = {
                    '=== АНАЛИЗ АРИТМИИ ===', '===';
                    'Тип распределения', results.arrhythmiaAnalysis.distributionType;
                    'Количество пиков', sprintf('%d', results.arrhythmiaAnalysis.nPeaks);
                    'Степень аритмии', results.arrhythmiaAnalysis.arrhythmiaSeverity;
                    'Индекс аритмии 1', sprintf('%.2f', results.arrhythmiaAnalysis.arrhythmiaIndex1);
                    'Индекс аритмии 2', sprintf('%.2f', results.arrhythmiaAnalysis.arrhythmiaIndex2);
                    'Тип ритма', results.rhythmType;
                    'Асимметрия', sprintf('%.3f', results.skewnessRR);
                    'Эксцесс', sprintf('%.3f', results.kurtosisRR);
                    'Интерпретация', results.arrhythmiaAnalysis.interpretation;
                };
                
                % Объединяем данные
                tableData = [basicData; arrhythmiaData];
            else
                tableData = basicData;
            end
            
            app.ResultsTable.Data = tableData;
            app.ResultsTable.ColumnWidth = {220, 150};
        end
        
        function createArrhythmiaAnalysisTab(app)
            % Создание вкладки для детального анализа аритмии
            
            % Панель для индивидуального анализа
            individualPanel = uipanel(app.ArrhythmiaTab);
            individualPanel.Title = 'Индивидуальный анализ распределения';
            individualPanel.Position = [20 350 400 300];
            
            % Кнопка для детального анализа текущего файла
            app.DetailedArrhythmiaButton = uibutton(individualPanel, 'push');
            app.DetailedArrhythmiaButton.Position = [20 240 360 30];
            app.DetailedArrhythmiaButton.Text = 'Детальный анализ аритмии текущего файла';
            app.DetailedArrhythmiaButton.ButtonPushedFcn = createCallbackFcn(app, @detailedArrhythmiaButtonPushed, true);
            
            % Текстовое поле для вывода интерпретации
            app.ArrhythmiaInterpretationText = uitextarea(individualPanel);
            app.ArrhythmiaInterpretationText.Position = [20 20 360 210];
            app.ArrhythmiaInterpretationText.Value = {'Сначала выполните расчёт параметров ВСР на вкладке "Одиночный анализ", затем нажмите кнопку для получения детального анализа аритмии.'};
            app.ArrhythmiaInterpretationText.Editable = 'off';
            
            % Панель для группового анализа аритмии
            groupPanel = uipanel(app.ArrhythmiaTab);
            groupPanel.Title = 'Групповой анализ аритмии';
            groupPanel.Position = [440 20 720 630];
            
            % Оси для сравнительных гистограмм аритмии
            app.ComparativeHistogramAxes = uiaxes(groupPanel);
            app.ComparativeHistogramAxes.Position = [20 400 680 200];
            title(app.ComparativeHistogramAxes, 'Сравнительные гистограммы распределения RR-интервалов');
            xlabel(app.ComparativeHistogramAxes, 'RR-интервалы (мс)');
            ylabel(app.ComparativeHistogramAxes, 'Частота');
            grid(app.ComparativeHistogramAxes, 'on');
            
            % Таблица статистики аритмии по группам
            app.ArrhythmiaStatsTable = uitable(groupPanel);
            app.ArrhythmiaStatsTable.Position = [20 20 680 350];
            app.ArrhythmiaStatsTable.ColumnName = {'Параметр аритмии', 'До нагрузки', 'После нагрузки', 'Изменение'};
            app.ArrhythmiaStatsTable.ColumnWidth = {200, 120, 120, 120};
        end
        
        function detailedArrhythmiaButtonPushed(app, ~)
            % Детальный анализ аритмии для текущего файла
            
            if isempty(app.HRVResults) || ~isfield(app.HRVResults, 'arrhythmiaAnalysis')
                app.ArrhythmiaInterpretationText.Value = {'Сначала выполните расчёт параметров ВСР на вкладке "Одиночный анализ"'};
                return;
            end
            
            analysis = app.HRVResults.arrhythmiaAnalysis;
            
            % Формирование детального отчёта
            report = {};
            
            report{end+1} = '=== ДЕТАЛЬНЫЙ АНАЛИЗ АРИТМИИ ===';
            report{end+1} = sprintf('Тип распределения: %s', analysis.distributionType);
            report{end+1} = sprintf('Количество значимых пиков: %d', analysis.nPeaks);
            report{end+1} = sprintf('Степень аритмии: %s', analysis.arrhythmiaSeverity);
            
            if analysis.nPeaks >= 2
                report{end+1} = '';
                report{end+1} = '=== ХАРАКТЕРИСТИКИ ПИКОВ ===';
                for i = 1:min(analysis.nPeaks, 5) % Ограничиваем 5 пиками
                    report{end+1} = sprintf('Пик %d: %.0f мс (высота: %.0f)', ...
                        i, analysis.peakPositions(i), analysis.peakHeights(i));
                end
                
                if analysis.nPeaks > 5
                    report{end+1} = sprintf('... и еще %d пиков', analysis.nPeaks - 5);
                end
                
                report{end+1} = '';
                report{end+1} = '=== ИНДЕКСЫ АРИТМИИ ===';
                report{end+1} = sprintf('Индекс аритмии 1 (Пик2/Пик1): %.2f', analysis.arrhythmiaIndex1);
                report{end+1} = sprintf('Индекс аритмии 2 (Сумма доп.пиков/Пик1): %.2f', analysis.arrhythmiaIndex2);
                report{end+1} = sprintf('Среднее расстояние между пиками: %.1f мс (%.1f%% от среднего RR)', ...
                    analysis.meanPeakDistance, analysis.relativePeakDistance);
            end
            
            report{end+1} = '';
            report{end+1} = '=== ИНТЕРПРЕТАЦИЯ ===';
            report{end+1} = analysis.interpretation;
            
            report{end+1} = '';
            report{end+1} = '=== РЕКОМЕНДАЦИИ ДЛЯ СПОРТСМЕНА ===';
            if analysis.nPeaks == 1
                report{end+1} = '• Регулярный синусовый ритм';
                report{end+1} = '• Адаптация сердца к нагрузкам адекватная';
                report{end+1} = '• Рекомендуется продолжать тренировки в обычном режиме';
            elseif analysis.nPeaks == 2 && analysis.arrhythmiaIndex1 < 0.3
                report{end+1} = '• Лёгкая синусовая аритмия (часто встречается у спортсменов)';
                report{end+1} = '• Возможна дыхательная аритмия - вариант нормы';
                report{end+1} = '• Рекомендуется наблюдение и контроль нагрузок';
            elseif analysis.nPeaks >= 2 && analysis.arrhythmiaIndex1 >= 0.3
                report{end+1} = '• Выраженная аритмия';
                report{end+1} = '• Возможно нарушение вегетативной регуляции сердца';
                report{end+1} = '• Рекомендуется консультация спортивного врача';
            elseif analysis.nPeaks >= 3
                report{end+1} = '• Мультимодальное распределение (выраженная аритмия)';
                report{end+1} = '• Возможны множественные нарушения ритма';
                report{end+1} = '• Требуется углублённое кардиологическое обследование';
                report{end+1} = '• Временное снижение тренировочных нагрузок';
            end
            
            % Обновление текстового поля
            app.ArrhythmiaInterpretationText.Value = report;
        end
        
        function updateBatchResultsTable(app)
            % Обновление таблицы результатов пакетного анализа
            
            if isempty(app.BatchResults)
                return;
            end
            
            results = app.BatchResults;
            
            % 1. Таблица с основными статистиками TINN
            statsData = {
                'Ср. TINN (мс)', ...
                sprintf('%.2f', results.beforeStats.mean), ...
                sprintf('%.2f', results.afterStats.mean);
                
                'SD TINN (мс)', ...
                sprintf('%.2f', results.beforeStats.std), ...
                sprintf('%.2f', results.afterStats.std);
                
                'SEM TINN (мс)', ...
                sprintf('%.2f', results.beforeStats.sem), ...
                sprintf('%.2f', results.afterStats.sem);
                
                'N (количество)', ...
                sprintf('%d', results.beforeStats.n), ...
                sprintf('%d', results.afterStats.n);
            };
            
            app.BatchResultsTable.Data = statsData;
            app.BatchResultsTable.ColumnName = {'Параметр', 'До нагрузки', 'После нагрузки'};
            app.BatchResultsTable.ColumnWidth = {180, 100, 100};
            
            % 2. Таблица с результатами статистического теста
            testData = {};
            row = 1;
            
            if strcmp(results.testUsed, 'ttest')
                testData{row, 1} = 'Использованный тест';
                testData{row, 2} = 'Двухвыборочный t-тест';
                testData{row, 3} = '';
                row = row + 1;
                
                testData{row, 1} = 'p-value';
                testData{row, 2} = sprintf('%.4f', results.ttest.p);
                % Используем функцию из отдельного файла
                testData{row, 3} = interpretPValueSimple(results.ttest.p);
                row = row + 1;
                
                testData{row, 1} = 'Статистический вывод';
                testData{row, 2} = interpretTestResultSimple(results.ttest.p);
                testData{row, 3} = '';
                
            elseif strcmp(results.testUsed, 'wilcoxon')
                testData{row, 1} = 'Использованный тест';
                testData{row, 2} = 'Критерий Уилкоксона';
                testData{row, 3} = '';
                row = row + 1;
                
                testData{row, 1} = 'p-value';
                testData{row, 2} = sprintf('%.4f', results.wilcoxon.p);
                % Используем функцию из отдельного файла
                testData{row, 3} = interpretPValueSimple(results.wilcoxon.p);
                row = row + 1;
                
                testData{row, 1} = 'Статистический вывод';
                testData{row, 2} = interpretTestResultSimple(results.wilcoxon.p);
                testData{row, 3} = '';
                
            else
                testData{row, 1} = 'Статистический тест';
                testData{row, 2} = 'Не выполнен';
                testData{row, 3} = results.noTestReason;
            end
            
            app.StatisticsTable.Data = testData;
            app.StatisticsTable.ColumnName = {'Статистика', 'Значение', 'Интерпретация'};
            app.StatisticsTable.ColumnWidth = {180, 120, 120};
        end
        
        function interpretation = interpretPValueSimple(p)
            % Простая интерпретация p-value
            if isnan(p)
                interpretation = 'Невозможно вычислить';
            elseif p < 0.001
                interpretation = 'p < 0.001 (***)';
            elseif p < 0.01
                interpretation = 'p < 0.01 (**)';
            elseif p < 0.05
                interpretation = 'p < 0.05 (*)';
            else
                interpretation = 'p > 0.05 (не значимо)';
            end
        end
        
        function result = interpretTestResultSimple(p)
            % Простая интерпретация результата теста
            if isnan(p)
                result = 'Ошибка в расчёте';
            elseif p < 0.05
                result = 'Есть статистически значимые различия';
            else
                result = 'Нет статистически значимых различий';
            end
        end
        
        function rhythmogramZoomInButtonPushed(app, ~)
            % Приближение для ритмограммы (ручная реализация)
            try
                % Получаем текущие пределы осей
                xlim_current = app.RhythmogramAxes.XLim;
                ylim_current = app.RhythmogramAxes.YLim;
                
                % Вычисляем центр текущих пределов
                x_center = mean(xlim_current);
                y_center = mean(ylim_current);
                
                % Вычисляем диапазон
                x_range = xlim_current(2) - xlim_current(1);
                y_range = ylim_current(2) - ylim_current(1);
                
                % Уменьшаем диапазон в 2 раза для приближения
                zoom_factor = 0.5;
                new_x_range = x_range * zoom_factor;
                new_y_range = y_range * zoom_factor;
                
                % Устанавливаем новые пределы
                app.RhythmogramAxes.XLim = [x_center - new_x_range/2, x_center + new_x_range/2];
                app.RhythmogramAxes.YLim = [y_center - new_y_range/2, y_center + new_y_range/2];
                
            catch ME
                uialert(app.UIFigure, sprintf('Ошибка при приближении: %s', ME.message), 'Ошибка');
            end
        end
        
        function rhythmogramResetButtonPushed(app, ~)
            % Сброс масштаба для ритмограммы
            try
                % Получаем данные из текущего графика
                lines = findobj(app.RhythmogramAxes, 'Type', 'line');
                if ~isempty(lines)
                    % Находим минимальные и максимальные значения данных
                    xData = [];
                    yData = [];
                    for i = 1:length(lines)
                        xData = [xData; lines(i).XData(:)];
                        yData = [yData; lines(i).YData(:)];
                    end
                    
                    if ~isempty(xData)
                        % Устанавливаем пределы с небольшим запасом
                        x_min = min(xData);
                        x_max = max(xData);
                        y_min = min(yData);
                        y_max = max(yData);
                        
                        x_pad = (x_max - x_min) * 0.05;
                        y_pad = (y_max - y_min) * 0.05;
                        
                        if x_pad == 0, x_pad = 1; end
                        if y_pad == 0, y_pad = 1; end
                        
                        app.RhythmogramAxes.XLim = [x_min - x_pad, x_max + x_pad];
                        app.RhythmogramAxes.YLim = [y_min - y_pad, y_max + y_pad];
                    end
                end
                
                % Обновляем график
                drawnow;
                
            catch ME
                uialert(app.UIFigure, sprintf('Ошибка при сбросе масштаба: %s', ME.message), 'Ошибка');
            end
        end
        
        function spectrumZoomInButtonPushed(app, ~)
            % Приближение для осциллограммы (ручная реализация)
            try
                % Получаем текущие пределы осей
                xlim_current = app.SpectrumAxes.XLim;
                ylim_current = app.SpectrumAxes.YLim;
                
                % Вычисляем центр текущих пределов
                x_center = mean(xlim_current);
                y_center = mean(ylim_current);
                
                % Вычисляем диапазон
                x_range = xlim_current(2) - xlim_current(1);
                y_range = ylim_current(2) - ylim_current(1);
                
                % Уменьшаем диапазон в 2 раза для приближения
                zoom_factor = 0.5;
                new_x_range = x_range * zoom_factor;
                new_y_range = y_range * zoom_factor;
                
                % Устанавливаем новые пределы
                app.SpectrumAxes.XLim = [x_center - new_x_range/2, x_center + new_x_range/2];
                app.SpectrumAxes.YLim = [y_center - new_y_range/2, y_center + new_y_range/2];
                
            catch ME
                uialert(app.UIFigure, sprintf('Ошибка при приближении: %s', ME.message), 'Ошибка');
            end
        end
        
        function plotComparativeTINNHistograms(app, beforeTINN, afterTINN, binWidth)
            % Построение сравнительных гистограмм TINN для групп до и после нагрузки
            
            cla(app.ComparativeHistAxes);
            
            if isempty(beforeTINN) || isempty(afterTINN)
                return;
            end
            
            % Определяем общий диапазон для обеих групп
            minTINN = min([beforeTINN, afterTINN]);
            maxTINN = max([beforeTINN, afterTINN]);
            
            % Создаем общие границы бинов
            edges = floor(minTINN/binWidth)*binWidth : binWidth : ceil(maxTINN/binWidth)*binWidth;
            
            hold(app.ComparativeHistAxes, 'on');
            
            % Гистограмма для группы "до нагрузки"
            histogram(app.ComparativeHistAxes, beforeTINN, edges, ...
                'FaceColor', 'blue', 'FaceAlpha', 0.6, 'EdgeColor', 'blue', ...
                'DisplayName', 'До нагрузки');
            
            % Гистограмма для группы "после нагрузки"
            histogram(app.ComparativeHistAxes, afterTINN, edges, ...
                'FaceColor', 'red', 'FaceAlpha', 0.6, 'EdgeColor', 'red', ...
                'DisplayName', 'После нагрузки');
            
            % Рассчитываем и отображаем средние значения
            meanBefore = mean(beforeTINN);
            meanAfter = mean(afterTINN);
            
            ylims = ylim(app.ComparativeHistAxes);
            
            % Вертикальные линии для средних значений
            plot(app.ComparativeHistAxes, [meanBefore, meanBefore], ylims, ...
                'b--', 'LineWidth', 2, 'DisplayName', sprintf('Среднее до: %.1f', meanBefore));
            plot(app.ComparativeHistAxes, [meanAfter, meanAfter], ylims, ...
                'r--', 'LineWidth', 2, 'DisplayName', sprintf('Среднее после: %.1f', meanAfter));
            
            hold(app.ComparativeHistAxes, 'off');
            
            % Настройка графика
            legend(app.ComparativeHistAxes, 'Location', 'best');
            title(app.ComparativeHistAxes, sprintf('Сравнительные гистограммы TINN (binWidth = %d мс)', binWidth));
            xlabel(app.ComparativeHistAxes, 'TINN (мс)');
            ylabel(app.ComparativeHistAxes, 'Количество наблюдений');
            grid(app.ComparativeHistAxes, 'on');
            
            % Выводим статистику в консоль
            fprintf('\n=== СТАТИСТИКА РАСПРЕДЕЛЕНИЙ TINN ===\n');
            fprintf('До нагрузки:\n');
            fprintf('  Среднее: %.2f ± %.2f мс\n', meanBefore, std(beforeTINN));
            fprintf('  Медиана: %.2f мс\n', median(beforeTINN));
            fprintf('  N = %d\n', length(beforeTINN));
            fprintf('После нагрузки:\n');
            fprintf('  Среднее: %.2f ± %.2f мс\n', meanAfter, std(afterTINN));
            fprintf('  Медиана: %.2f мс\n', median(afterTINN));
            fprintf('  N = %d\n', length(afterTINN));
            fprintf('Разница средних: %.2f мс\n', meanAfter - meanBefore);
            fprintf('Относительное изменение: %.1f%%\n', (meanAfter - meanBefore)/meanBefore*100);
            fprintf('==============================\n');
        end
        
        function spectrumResetButtonPushed(app, ~)
            % Сброс масштаба для осциллограммы
            try
                % Получаем данные из текущего графика
                lines = findobj(app.SpectrumAxes, 'Type', 'line');
                if ~isempty(lines)
                    % Находим минимальные и максимальные значения данных
                    xData = [];
                    yData = [];
                    for i = 1:length(lines)
                        xData = [xData; lines(i).XData(:)];
                        yData = [yData; lines(i).YData(:)];
                    end
                    
                    if ~isempty(xData)
                        % Устанавливаем пределы с небольшим запасом
                        x_min = min(xData);
                        x_max = max(xData);
                        y_min = min(yData);
                        y_max = max(yData);
                        
                        x_pad = (x_max - x_min) * 0.05;
                        y_pad = (y_max - y_min) * 0.05;
                        
                        if x_pad == 0, x_pad = 1; end
                        if y_pad == 0, y_pad = 1; end
                        
                        app.SpectrumAxes.XLim = [x_min - x_pad, x_max + x_pad];
                        app.SpectrumAxes.YLim = [y_min - y_pad, y_max + y_pad];
                    end
                end
                
                % Обновляем график
                drawnow;
                
            catch ME
                uialert(app.UIFigure, sprintf('Ошибка при сбросе масштаба: %s', ME.message), 'Ошибка');
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Инициализация интерфейса при запуске
            app.UIFigure.Name = 'StudHRV - Анализ вариабельности сердечного ритма';
            app.UIFigure.Position = [100 100 1200 700];
            
            % Инициализация компонентов
            initializeComponents(app);
            
            % Установка значений по умолчанию
            app.MinPeakHeightEdit.Value = 0.5;
            app.BinWidthEdit.Value = 10;
            
            % Очистка графиков
            cla(app.RhythmogramAxes);
            cla(app.TINNHistogramAxes);
            cla(app.SpectrumAxes);
            
            % Настройка осей
            configureAxes(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
        end
    end

    methods (Access = public)

        % Construct app
        function app = StudHRV

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end